classdef Dog_vs_Cats < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PhotosListBoxLabel       matlab.ui.control.Label
        PhotosListBox            matlab.ui.control.ListBox
        UIAxes                   matlab.ui.control.UIAxes
        PredictButton            matlab.ui.control.Button
        PREDICTIONTextAreaLabel  matlab.ui.control.Label
        PREDICTIONTextArea       matlab.ui.control.TextArea
        DOGvsCATLabel            matlab.ui.control.Label
        SkalaSliderLabel         matlab.ui.control.Label
        SkalaSlider              matlab.ui.control.Slider
    end


    properties (Access = private)
        X; %Szerokosc oryginalnego zdjecia
        Y; %Wysokosc oryginalnego zdjecia
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %funkcja wywolywana przy uruchomieniu aplikacji
            %zapelnienie PhotosListBox'u zdjeciami 
            directory='.\';
            img_info=dir(fullfile(directory,'*.jpg'));
            for ind=1:length(img_info)
                app.PhotosListBox.Items{ind} = img_info(ind).name;
                
            end
            app.PhotosListBox.Value = app.PhotosListBox.Items{1}; %ustawienie 1 zdjecia jako domyslnej wybranej wartosci 
            app.SkalaSlider.Value=1;% ustawienie domyslnej wartosci suwaka na 1
            I=imread(app.PhotosListBox.Items{1}); %wczytanie domyslnie ustawionego zdjecia
            [y,x,~]=size(I); %pobranie rozmiaru oryginalnego zdjecia
            app.Y=y; %przypisanie tych wartosci do properties 
            app.X=x; 
            J=imresize(I,app.SkalaSlider.Value); %przeskalowanie zdjecia zgodnie z wartoscia suwaka
            imshow(J,'Parent',app.UIAxes); %wyswietlenie zdjecia w UIAxes
        end

        % Value changed function: PhotosListBox
        function PhotosListBoxValueChanged(app, event)
            %Funkcja wywolywana przy zmianie wybranego obiektu z pojemnika PhotosListBox
            %Przy zmianie zdjecia do properties przypisujemy rozmiary aktualnie wybranego, nowego zdjecia
            app.SkalaSlider.Value=1; %ustawienie domyslnej wartosci suwaka na 1
            value = app.PhotosListBox.Value   %pobranie sciezki do zdjecia wybranego z PhotosListBox
            I=imread(value); %wczytanie tego zdjecia przy wykorzystaniu pobranej sciezki
            [y,x,z]=size(I); 
            app.Y=y;
            app.X=x;
            value2=imresize(I,[round(app.Y*app.SkalaSlider.Value),round(app.X*app.SkalaSlider.Value)]); %przeskalowanie zdjecia zgodnie z proporcja wartosci ustawionej na suwaku wraz z rozmiarem oryginalnego zdjecia
            imshow(value2,'Parent',app.UIAxes); %wyswietlenie zdjecia
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            %funckja wywolywana przy wcisnieciu przycisku Predykcji. 
            img_path=app.PhotosListBox.Value; %pobranie sciezki wybranego zdjecia
            %otwarcie pliku tekstowego i zapisanie tam sciezki do wybranego zdjecia (wykonane w celu przekazania informacji o zdjeciu do skryptu Pythona)
            fid=fopen('zdjecie.txt','w'); 
            fprintf(fid,img_path);
            fclose(fid);
            %wywolanie skryptu Pythona (przekazanie zdjecia na splotowa siec neuronowa, ktorej zadaniem jest rozpoznanie obiektu na zdjecie i zaklasyfikowanie
            % go do jednej z 2 kategorii. Pod koniec obliczen, wynik zapisywany jest w pliku wyniki.txt
            !python psyikoty.py 
            %otwarcie pliku tekstowego ze zwroconym przez siec wynikiem oraz zapisanie go w formie lancucha znakow, ktory jest wyswietlany w PredictionTextArea
            fid=fopen('wyniki.txt','r');
            Data=fread(fid);
            CharData=char(Data);
            fclose(fid);
            l1=CharData(1);
            l2=CharData(2);
            l3=CharData(3);
            Predykcja=[l1,l2,l3];
            app.PREDICTIONTextArea.Value=Predykcja;
        end

        % Value changing function: SkalaSlider
        function SkalaSliderValueChanging(app, event)
            %Funkcja wywolywana przy zmianie wartosci na suwaku
            %sluzy do skalowania zdjec
            changingValue = event.Value; 
            %warunek zapewniajacy ze wartosc suwaka nie zejdzie ponizej 0.1
            if  changingValue <=0.1
                app.SkalaSlider.Value=0.1;
                changingValue=0.1;
            end
            %wczytanie i przeskalowanie zdjecia oraz jego wyswietlenie w UIAxes 
            I=imread(app.PhotosListBox.Value);
            J=imresize(I,[round(app.Y*round(changingValue,1)),round(app.X*round(changingValue,1))]);
            imshow(J,'Parent',app.UIAxes);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PhotosListBoxLabel = uilabel(app.UIFigure);
            app.PhotosListBoxLabel.Text = 'Photos';
            app.PhotosListBox = uilistbox(app.UIFigure);
            app.PhotosListBox.Items = {};
            app.PhotosListBox.ValueChangedFcn = createCallbackFcn(app, @PhotosListBoxValueChanged, true);
            app.PhotosListBox.Value = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Photo')
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.PREDICTIONTextAreaLabel = uilabel(app.UIFigure);
            app.PREDICTIONTextAreaLabel.Text = 'PREDICTION:';
            app.PREDICTIONTextArea = uitextarea(app.UIFigure);
            app.DOGvsCATLabel = uilabel(app.UIFigure);
            app.DOGvsCATLabel.Text = 'DOG vs CAT';
            app.SkalaSliderLabel = uilabel(app.UIFigure);
            app.SkalaSliderLabel.Text = 'Skala';
            app.SkalaSlider = uislider(app.UIFigure);
            app.SkalaSlider.Limits = [0 1];
            app.SkalaSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.SkalaSlider.ValueChangingFcn = createCallbackFcn(app, @SkalaSliderValueChanging, true);
            app.SkalaSlider.MinorTicks = [];
            app.SkalaSlider.Value = 0.1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Dog_vs_Cats

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end